classdef RetoF1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BienvenidaTab                   matlab.ui.container.Tab
        Image8                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ConocelapistaTab                matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Label_3                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ZonadecurvasquesemanjeaLabel    matlab.ui.control.Label
        InstruccionesTab                matlab.ui.container.Tab
        Label_8                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        InstruccionesdelsimuladorLabel  matlab.ui.control.Label
        DerrapesTab                     matlab.ui.container.Tab
        DerrapeenlacuartazonaSFButton   matlab.ui.control.Button
        DerrapeenlacuartazonaCFButton   matlab.ui.control.Button
        VmaxmsEditField                 matlab.ui.control.NumericEditField
        VmaxmsEditFieldLabel            matlab.ui.control.Label
        ngulodelperlategradosEditField  matlab.ui.control.NumericEditField
        ngulodelperlategradosEditFieldLabel  matlab.ui.control.Label
        SemodificarnlosvaloresinicialesLabel_2  matlab.ui.control.Label
        DerrapeenlatercerazonaSFButton  matlab.ui.control.Button
        DerrapeenlasegundazonaSFButton  matlab.ui.control.Button
        DerrapeenlaprimerazonaSFButton  matlab.ui.control.Button
        EnergaperdidaalderraparJEditField  matlab.ui.control.NumericEditField
        EnergaperdidaalderraparJEditFieldLabel  matlab.ui.control.Label
        CFAplcandofrenosSFSinaplicarfrenosLabel  matlab.ui.control.Label
        DerrapeenlasegundazonaCFButton  matlab.ui.control.Button
        DerrapeenlatercerazonaCFButton  matlab.ui.control.Button
        SemodificarnlosvaloresinicialesLabel  matlab.ui.control.Label
        DerrapeenlaprimerazonaCFButton  matlab.ui.control.Button
        CorrersimulacinButton           matlab.ui.control.Button
        VelocidadmsEditField            matlab.ui.control.NumericEditField
        VelocidadmsEditFieldLabel       matlab.ui.control.Label
        UsarvalorespersonalizadosButton  matlab.ui.control.Button
        UsarvaloresdefaultNohayderrapeButton  matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        LonituddelacurvamEditField      matlab.ui.control.NumericEditField
        LonituddelacurvamEditFieldLabel  matlab.ui.control.Label
        RadiodecurvaturamnimomEditField  matlab.ui.control.NumericEditField
        RadiodecurvaturamnimomEditFieldLabel  matlab.ui.control.Label
        EnergacinticaalderraparJEditField  matlab.ui.control.NumericEditField
        EnergacinticaalderraparJEditFieldLabel  matlab.ui.control.Label
        EnergacinticaenlacurvaJEditField  matlab.ui.control.NumericEditField
        EnergacinticaenlacurvaJEditFieldLabel  matlab.ui.control.Label
        EnergacinticainicialJEditField  matlab.ui.control.NumericEditField
        EnergacinticainicialJEditFieldLabel  matlab.ui.control.Label
        Aceleracinms2EditField          matlab.ui.control.NumericEditField
        Aceleracinms2EditFieldLabel     matlab.ui.control.Label
        CoeficientedefriccincinticoEditField  matlab.ui.control.NumericEditField
        CoeficientedefriccincinticoEditFieldLabel  matlab.ui.control.Label
        VelocidadinicialmsEditField     matlab.ui.control.NumericEditField
        VelocidadinicialmsEditFieldLabel  matlab.ui.control.Label
        Densidadvolumtricadelairekgm3EditField  matlab.ui.control.NumericEditField
        Densidadvolumtricadelairekgm3EditFieldLabel  matlab.ui.control.Label
        CoeficientedefriccnestticoEditField  matlab.ui.control.NumericEditField
        CoeficientedefriccnestticoEditFieldLabel  matlab.ui.control.Label
        CoeficientedeArrastreEditField  matlab.ui.control.NumericEditField
        CoeficientedeArrastreEditFieldLabel  matlab.ui.control.Label
        reatransversaldelautomEditField  matlab.ui.control.NumericEditField
        reatransversaldelautomEditFieldLabel  matlab.ui.control.Label
        MasadelautoKgEditField          matlab.ui.control.NumericEditField
        MasadelautoKgEditFieldLabel     matlab.ui.control.Label
        FuerzadelmotorNEditField        matlab.ui.control.NumericEditField
        FuerzadelmotorNEditFieldLabel   matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ColisionesTab                   matlab.ui.container.Tab
        Direccinauto2EditField          matlab.ui.control.EditField
        Direccinauto2EditFieldLabel     matlab.ui.control.Label
        Direccinauto1EditField          matlab.ui.control.EditField
        Direccinauto1EditFieldLabel     matlab.ui.control.Label
        ConstantederestitucinKEditField  matlab.ui.control.NumericEditField
        ConstantederestitucinKEditFieldLabel  matlab.ui.control.Label
        Resortedecompresinde2mLabel     matlab.ui.control.Label
        Vparaperder93deEMmsEditField    matlab.ui.control.NumericEditField
        Vparaperder93deEMmsLabel        matlab.ui.control.Label
        Vparaperder95deEMmsEditField    matlab.ui.control.NumericEditField
        Vparaperder95deEMmsEditFieldLabel  matlab.ui.control.Label
        Vparaperder98deEMmsEditField    matlab.ui.control.NumericEditField
        Vparaperder98deEMmsEditFieldLabel  matlab.ui.control.Label
        ColisincongradaLabel            matlab.ui.control.Label
        Velocidadauto2msEditField       matlab.ui.control.NumericEditField
        Velocidadauto2msEditFieldLabel  matlab.ui.control.Label
        Velocidadauto1msEditField       matlab.ui.control.NumericEditField
        Velocidadauto1msEditFieldLabel  matlab.ui.control.Label
        ColisinconautoLabel             matlab.ui.control.Label
        ColisingradasconparmetrosdefaultButton  matlab.ui.control.Button
        ColisinelsticaconparmetrosdefaultButton  matlab.ui.control.Button
        ColisinperfectamenteinelsticaconparametrosdefaultButton  matlab.ui.control.Button
        Velocidadinicialauto2msEditField  matlab.ui.control.NumericEditField
        Velocidadinicialauto2msEditFieldLabel  matlab.ui.control.Label
        MasagradasEditField             matlab.ui.control.EditField
        MasagradasEditFieldLabel        matlab.ui.control.Label
        Masaauto2kgEditField            matlab.ui.control.NumericEditField
        Masaauto2kgEditFieldLabel       matlab.ui.control.Label
        CoeficientedefriccincinticoEditField_2  matlab.ui.control.NumericEditField
        CoeficientedefriccincinticoEditField_2Label  matlab.ui.control.Label
        VelocidadinicialmsEditField_2   matlab.ui.control.NumericEditField
        VelocidadinicialmsEditField_2Label  matlab.ui.control.Label
        DensidaddelaireKgm3EditField    matlab.ui.control.NumericEditField
        DensidaddelaireKgm3EditFieldLabel  matlab.ui.control.Label
        CoeficientedefriccionestticoEditField  matlab.ui.control.NumericEditField
        CoeficientedefriccionestticoEditFieldLabel  matlab.ui.control.Label
        CoeficientedearrastreEditField  matlab.ui.control.NumericEditField
        CoeficientedearrastreEditFieldLabel  matlab.ui.control.Label
        reatransversaldelautomEditField_2  matlab.ui.control.NumericEditField
        reatransversaldelautomEditField_2Label  matlab.ui.control.Label
        MasadelautokgEditField          matlab.ui.control.NumericEditField
        MasadelautokgEditFieldLabel     matlab.ui.control.Label
        FuerzadelMotorNEditField        matlab.ui.control.NumericEditField
        FuerzadelMotorNEditFieldLabel   matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Label_7                         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        HallazgosTab                    matlab.ui.container.Tab
        Direccinauto2DerechaLabel       matlab.ui.control.Label
        Direccinauto1izquierdaLabel     matlab.ui.control.Label
        v2157msLabel                    matlab.ui.control.Label
        v17836msLabel                   matlab.ui.control.Label
        ChoqueelsticoLabel              matlab.ui.control.Label
        DireccinderechaambosLabel       matlab.ui.control.Label
        v1v27836msLabel                 matlab.ui.control.Label
        ChoquecongradasLabel            matlab.ui.control.Label
        ChoquePerfectamenteinlasticoLabel  matlab.ui.control.Label
        ConstantederesitucinK109105Label  matlab.ui.control.Label
        Vparaperder932186msLabel        matlab.ui.control.Label
        Vparaperder952233msLabel        matlab.ui.control.Label
        Vparaperder982304msLabel        matlab.ui.control.Label
        ColisionesLabel                 matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        EnergaperdidaJLabel             matlab.ui.control.Label
        HALLAZGOSDELASIMULACINLabel     matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        PeraltegradosLabel              matlab.ui.control.Label
        ams2Label                       matlab.ui.control.Label
        VelocidadmsLabel                matlab.ui.control.Label
        Valoresmnimosparaderrapezona4Label  matlab.ui.control.Label
        Valoresmnimosparaderrapezona2Label  matlab.ui.control.Label
        Valoresmnimosparaderrapezona3Label  matlab.ui.control.Label
        Valoresmnimosparaderrapezona1Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UsarvaloresdefaultNohayderrapeButton
        function UsarvaloresdefaultNohayderrapeButtonPushed(app, event)
            fmdef = 100;
            mdef = 789;
            Atdef = 0.3;
            cddef = 0.85;
            musdef = 0.9;
            %gdef= 9.8;
            Densidaddef = 1.225;
            videf = 0;
            mukdef = 0.3;

            app.FuerzadelmotorNEditField.Value = fmdef;
            app.MasadelautoKgEditField.Value = mdef;
            app.reatransversaldelautomEditField.Value = Atdef;
            app.CoeficientedeArrastreEditField.Value = cddef;
            app.CoeficientedefriccnestticoEditField.Value = musdef;
            app.Densidadvolumtricadelairekgm3EditField.Value = Densidaddef;
            app.VelocidadinicialmsEditField.Value = videf;
            app.CoeficientedefriccincinticoEditField.Value = mukdef;


        end

        % Button pushed function: UsarvalorespersonalizadosButton
        function UsarvalorespersonalizadosButtonPushed(app, event)
            fmper = 0;
            mper = 0;
            Atper = 0;
            cdper = 0;
            musper = 0;
            %gdef= 9.8;
            Densidadper = 0;
            viper = 0;
            mukper = 0;

            app.FuerzadelmotorNEditField.Value = fmper;
            app.MasadelautoKgEditField.Value = mper;
            app.reatransversaldelautomEditField.Value = Atper;
            app.CoeficientedeArrastreEditField.Value = cdper;
            app.CoeficientedefriccnestticoEditField.Value = musper;
            app.Densidadvolumtricadelairekgm3EditField.Value = Densidadper;
            app.VelocidadinicialmsEditField.Value = viper;
            app.CoeficientedefriccincinticoEditField.Value = mukper;
        end

        % Button pushed function: CorrersimulacinButton
        function CorrersimulacinButtonPushed(app, event)
    
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
         
        end

        % Button pushed function: DerrapeenlaprimerazonaCFButton
        function DerrapeenlaprimerazonaCFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 23.5; %primera zona cf
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0.3;
            muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
           
        end

        % Button pushed function: DerrapeenlasegundazonaCFButton
        function DerrapeenlasegundazonaCFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 12.4; %segunda zona cf
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0.3;
            muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
            
        end

        % Button pushed function: DerrapeenlatercerazonaCFButton
        function DerrapeenlatercerazonaCFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 12.36995; %tercera zona cf  
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0.3;
            muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
         
        end

        % Button pushed function: DerrapeenlacuartazonaCFButton
        function DerrapeenlacuartazonaCFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 9.71; %cuarta zona cf  
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0.3;
            muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
         
        end

        % Button pushed function: DerrapeenlaprimerazonaSFButton
        function DerrapeenlaprimerazonaSFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 23.5; %primera zona Sf
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0;
           %%% muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-(1/2)*Densidad*At*cd*Vd(1,1)^2);
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-(1/2)*Densidad*At*cd*Vd(i+1,1)^2);
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
     
        end

        % Button pushed function: DerrapeenlasegundazonaSFButton
        function DerrapeenlasegundazonaSFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 12.4; %segunda zona Sf
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0;
           %%% muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-(1/2)*Densidad*At*cd*Vd(1,1)^2);
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-(1/2)*Densidad*At*cd*Vd(i+1,1)^2);
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x

        end

        % Button pushed function: DerrapeenlatercerazonaSFButton
        function DerrapeenlatercerazonaSFButtonPushed(app, event)
            pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 12.36995; %tercera zona Sf
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0;
           %%% muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-(1/2)*Densidad*At*cd*Vd(1,1)^2);
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-(1/2)*Densidad*At*cd*Vd(i+1,1)^2);
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
 
        end

        % Button pushed function: DerrapeenlacuartazonaSFButton
        function DerrapeenlacuartazonaSFButtonPushed(app, event)
             pista = app.UIAxes;
            x1gradas = 10;
            y1gradas = 170;
            x2gradas = 280;
            y2gradas = 120;
            
            x3gradas = 130;
            y3gradas = 200;
            x4gradas = 230;
            y4gradas = 50;
            
            Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
            Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
            Agradas = inv(Xgradas)*Ygradas;
            
            xgradas = linspace(x1gradas,x2gradas,201);
            
            fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);
            
            dxgradas = [];
            dygradas = [];
            dsgradas = [];
            der2xgradas = [];
            der2ygradas =[];
            Rgradas = [];
            cgradas = [];
            
            for i = 1:length(xgradas)-1
                dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
                dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
                dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
                der1xgradas = dxgradas./dsgradas;
                der1ygradas = dygradas./dsgradas; 
            end
            
            
            for i = 1:length(der1xgradas)-1
                der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
                der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
            % Radio de curvatura
                Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
            end
            
            longitudgradas = sum(dsgradas);
            Radmingradas = min(Rgradas);


            for j = 2:length(der1ygradas)-1
                if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
                    cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
                end
            end
            
            indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
            indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
            R1gradas = Rgradas(indice1gradas);
            R2gradas = Rgradas(indice2gradas);
            
            t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
            t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));
            
            
            m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
            b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
            coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
            tangente1gradas = m1gradas*coorx1gradas + b1gradas;% Recta tangente al punto critico
            
            m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
            b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
            coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
            tangente2gradas = m2gradas*coorx2gradas + b2gradas;
            
            
            perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
            perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);
            
            paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
            paralela2gradas = tangente2gradas-23;


            hold(pista, 'on')
            %plot(xgradas,fcurvagradas,'LineWidth',2)
            plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
            %plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
            %plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
            plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
            plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)
            
            plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)
            
            punto1 = 60;
            punto2 = 300;
            
            punto3 = 4.15361;
            punto4 = 243.7138;
            
            punto5 = -4.224197604174;
            punto6 = 251.2098386066039;
            
            punto7 = 52.6567518574205;
            punto8 = 307.6498504754727;
            
            punto9= 168.5857808645159;
            punto10 = 71.6697939358922;
            
            punto11 = 227.7527419724195;
            punto12 = 16.9324342207233;
            
            punto13 = 220.44968256758;
            punto14 = 9.435308434908;
            
            punto15 = 161.346511139683;
            punto16 = 64.7247973200413;
            
            grada1 = [punto1 punto3];
            grada2 = [punto2 punto4];
            
            grada3 = [punto3 punto5];
            grada4 = [punto4 punto6];
            
            grada5 = [punto1 punto7];
            grada6 = [punto2 punto8];
            
            grada7 = [punto5 punto7];
            grada8 = [punto6 punto8];
            
            grada9 = [punto9 punto11];
            grada10 = [punto10 punto12];
            
            grada11 = [punto15 punto13];
            grada12 = [punto16 punto14];
            
            grada13 = [punto9 punto15];
            grada14 = [punto10 punto16];
            
            grada15 = [punto11 punto13];
            grada16 = [punto12 punto14];
            
            plot (pista,grada1, grada2,'g','LineWidth',2)
            plot(pista,grada3, grada4,'g','LineWidth',2)
            plot(pista,grada5, grada6,'g','LineWidth',2)
            plot(pista,grada7, grada8,'g','LineWidth',2)
            plot(pista,grada9, grada10,'g','LineWidth',2)
            plot(pista,grada11, grada12,'g','LineWidth',2)
            plot(pista,grada13, grada14,'g','LineWidth',2)
            plot(pista,grada15, grada16,'g','LineWidth',2)
            
            xi = 10 ; yi = 170;  % punto inicial de la curva
            xf = 280 ; yf = 120; % punto final de la curva
            
            xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
            yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva
            
            p = polyfit(xr,yr,3); % ajuste de curva de grado 3
            x1 = linspace(xi,xf);
            f1 = polyval(p,x1);
            % grafica y formato de la misma
            plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')
            %hold (pista, 'on')
            grid (pista, 'on')
            plot(pista,x1,f1,'r--', 'Color','blue')
            daspect([1 1 1]);
            title(pista,'Zona de curvas de la pista')
            q = polyder(p); % derivada del polinomio que modela la zona de curvas
            q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
            pendiente(1,1) = polyval(q, xi);
            
            f1d = polyval(q,xi:xf);
            f2d = polyval(q2,xi:xf);
            
            fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva
            
            L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva
            
            R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura
            app.LonituddelacurvamEditField.Value = L;


            app.FuerzadelmotorNEditField.Value = 100;
            app.MasadelautoKgEditField.Value = 789;
            app.reatransversaldelautomEditField.Value = 0.3;
            app.CoeficientedeArrastreEditField.Value = 0.85;
            app.CoeficientedefriccnestticoEditField.Value = 0.9;
            app.Densidadvolumtricadelairekgm3EditField.Value = 1.225;
            app.VelocidadinicialmsEditField.Value = 9.71; %cuarta zona Sf
            Fm = app.FuerzadelmotorNEditField.Value;
            m = app.MasadelautoKgEditField.Value;
            At = app.reatransversaldelautomEditField.Value;
            cd = app.CoeficientedeArrastreEditField.Value;
            mus = app.CoeficientedefriccnestticoEditField.Value;
            g = 9.8;
            Densidad = app.Densidadvolumtricadelairekgm3EditField.Value;
            v(1,1) = app.VelocidadinicialmsEditField.Value;
            a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
            ang(1,1) = atand(pendiente(1,1));
            vx(1,1) = v(1,1)*cosd(ang(1,1));
            vy(1,1) = v(1,1)*sind(ang(1,1));
            x(1,1) = xi;
            y(1,1) = yi;
            
            Eki = (1/2)*m*(v(1,1)^2);
            app.EnergacinticainicialJEditField.Value = Eki;
            Ekn(1,1) = Eki; 
            
            h = 0.01;
            
            for i = 1:100000
                x(i+1,1) = x(i,1)+vx(i,1)*h;
                y(i+1,1) = y(i,1)+vy(i,1)*h;
                pendiente(i+1,1) = polyval(q,x(i+1));
            
                ang(i+1,1) = atand(pendiente(i+1,1));
                app.ngulodelperlategradosEditField.Value=ang(i+1,1);
                v(i+1,1) = v(i,1)+a(i,1)*h;
                vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
                vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
                Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
                app.EnergacinticaenlacurvaJEditField.Value = Ekn(i+1,1);
                f1dfor = polyval(q,x(i+1));
                f2dfor = polyval(q2,x(i+1));
                Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);
            
                Vmax = sqrt(mus*g*Rfor);
                app.VmaxmsEditField.Value=Vmax;
                app.VelocidadmsEditField.Value = v(i+1,1);
                if v(i+1) > Vmax
                    warndlg('Se originó un derrape','Advertencia');
                    break
                end
            
                a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;
                app.Aceleracinms2EditField.Value = a(i+1,1);
                if x(i+1,1)>xf
                    break
                end
            end
            %hold (pista, 'on')
            plot(pista,x,y,'*','Color','cyan')
            Ekd(1,1) = Ekn(end);
            xd(1,1) = x(end);
            yd(1,1) = y(end);
            md = polyval(q,xd);
            angd = atand(md);
            Vd(1,1) = v(end);
            app.CoeficientedefriccincinticoEditField.Value = 0;
           %%% muk = app.CoeficientedefriccincinticoEditField.Value;
            ad(1,1) = (-(1/2)*Densidad*At*cd*Vd(1,1)^2);
            vxd(1,1) = Vd(1,1)*cosd(angd);
            vyd(1,1) = Vd(1,1)*sind(angd);
            
            for i = 1 : 2000000
                xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
                yd(i+1,1) = yd(i,1) +vyd(i,1)*h;
            
                Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
                vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
                vyd(i+1,1) = Vd(i+1,1)*sind(angd);
                Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
                ad(i+1,1) = (-(1/2)*Densidad*At*cd*Vd(i+1,1)^2);
                app.VelocidadmsEditField.Value = Vd(i+1,1);
                app.Aceleracinms2EditField.Value = ad(i+1,1);
                app.EnergacinticaalderraparJEditField.Value = Ekd(i+1,1);
                if Vd(i+1,1) <= 0
                    break
                end
            end
            
            
            Eperdida = Ekd(end)-Eki;
            app.EnergaperdidaalderraparJEditField.Value=Eperdida;
            %hold (pista, 'on')
            plot(pista,xd,yd,'red','LineWidth',2)
            
            Rmin = min(R); % Radio de curvatura minimo
            %Rmax = max(R); % Radio de curvatura maximo
            app.RadiodecurvaturamnimomEditField.Value = Rmin;
            syms x % Se declara la variable x
            % se define el polinomio que modela la zona de curvas
            funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
            funpolprima = diff(funpol); % Se deriva el polinomio
            maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                               % y se despeja para x
          
        end

        % Button pushed function: 
        % ColisinperfectamenteinelsticaconparametrosdefaultButton
        function ColisinperfectamenteinelsticaconparametrosdefaultButtonPushed(app, event)

pista = app.UIAxes2;
x1gradas = 10;
y1gradas = 170;
x2gradas = 280;
y2gradas = 120;

x3gradas = 130;
y3gradas = 200;
x4gradas = 230;
y4gradas = 50;

Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
Agradas = inv(Xgradas)*Ygradas;

xgradas = linspace(x1gradas,x2gradas,201);

fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);

dxgradas = [];
dygradas = [];
dsgradas = [];
der2xgradas = [];
der2ygradas =[];
Rgradas = [];
cgradas = [];

for i = 1:length(xgradas)-1
    dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
    dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
    dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
    der1xgradas = dxgradas./dsgradas;
    der1ygradas = dygradas./dsgradas; 
end


for i = 1:length(der1xgradas)-1
    der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
    der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
% Radio de curvatura
    Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
end

longitudgradas = sum(dsgradas);
Radmingradas = min(Rgradas);


for j = 2:length(der1ygradas)-1
    if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
        cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
    end
end

indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
R1gradas = Rgradas(indice1gradas);
R2gradas = Rgradas(indice2gradas);

t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));


m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
tangente1gradas = m1gradas*coorx1gradas + b1gradas;% REcta tangente al punto critico

m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
tangente2gradas = m2gradas*coorx2gradas + b2gradas;


perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);

paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
paralela2gradas = tangente2gradas-23;


hold(pista,"on")  
%plot(xgradas,fcurvagradas,'LineWidth',2)
plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
%plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
%plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)

plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)

punto1 = 60;
punto2 = 300;

punto3 = 4.15361;
punto4 = 243.7138;

punto5 = -4.224197604174;
punto6 = 251.2098386066039;

punto7 = 52.6567518574205;
punto8 = 307.6498504754727;

punto9= 168.5857808645159;
punto10 = 71.6697939358922;

punto11 = 227.7527419724195;
punto12 = 16.9324342207233;

punto13 = 220.44968256758;
punto14 = 9.435308434908;

punto15 = 161.346511139683;
punto16 = 64.7247973200413;

grada1 = [punto1 punto3];
grada2 = [punto2 punto4];

grada3 = [punto3 punto5];
grada4 = [punto4 punto6];

grada5 = [punto1 punto7];
grada6 = [punto2 punto8];

grada7 = [punto5 punto7];
grada8 = [punto6 punto8];

grada9 = [punto9 punto11];
grada10 = [punto10 punto12];

grada11 = [punto15 punto13];
grada12 = [punto16 punto14];

grada13 = [punto9 punto15];
grada14 = [punto10 punto16];

grada15 = [punto11 punto13];
grada16 = [punto12 punto14];


plot (pista,grada1, grada2,'g','LineWidth',2)
plot(pista,grada3, grada4,'g','LineWidth',2)
plot(pista,grada5, grada6,'g','LineWidth',2)
plot(pista,grada7, grada8,'g','LineWidth',2)
plot(pista,grada9, grada10,'g','LineWidth',2)
plot(pista,grada11, grada12,'g','LineWidth',2)
plot(pista,grada13, grada14,'g','LineWidth',2)
plot(pista,grada15, grada16,'g','LineWidth',2)

xi = 10 ; yi = 170;  % punto inicial de la curva
xf = 280 ; yf = 120; % punto final de la curva

xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva

p = polyfit(xr,yr,3); % ajuste de curva de grado 3
x1 = linspace(xi,xf);
f1 = polyval(p,x1);
% grafica y formato de la misma
plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')

grid(pista,"on") 
plot(pista,x1,f1,'r--', 'Color','blue')
daspect([1 1 1]);
title(pista,'Zona de curvas de la pista')
q = polyder(p); % derivada del polinomio que modela la zona de curvas
q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
pendiente(1,1) = polyval(q, xi);

f1d = polyval(q,xi:xf);
f2d = polyval(q2,xi:xf);

fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva

L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva

R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura


app.FuerzadelMotorNEditField.Value = 100;
Fm = 100;
app.MasadelautokgEditField.Value= 789;
m = 789;
app.reatransversaldelautomEditField_2.Value= 0.3;
At = 0.3;
app.CoeficientedearrastreEditField.Value= 0.85;
cd = 0.85;
app.CoeficientedefriccionestticoEditField.Value= 0.9;
mus = 0.9;
g = 9.8;
app.DensidaddelaireKgm3EditField.Value= 1.225;
Densidad = 1.225;
app.VelocidadinicialmsEditField_2.Value = 23.5;
v(1,1) = 23.5;
a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
ang(1,1) = atand(pendiente(1,1));
vx(1,1) = v(1,1)*cosd(ang(1,1));
vy(1,1) = v(1,1)*sind(ang(1,1));
x(1,1) = xi;
y(1,1) = yi;

Eki = (1/2)*m*(v(1,1)^2);
Ekn(1,1) = Eki; 

h = 0.01;

for i = 1:100000
    x(i+1,1) = x(i,1)+vx(i,1)*h;
    y(i+1,1) = y(i,1)+vy(i,1)*h;
   

    pendiente(i+1,1) = polyval(q,x(i+1));

    ang(i+1,1) = atand(pendiente(i+1,1));

    v(i+1,1) = v(i,1)+a(i,1)*h;
    vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
    vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
    Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
    f1dfor = polyval(q,x(i+1));
    f2dfor = polyval(q2,x(i+1));
    Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);

    Vmax = sqrt(mus*g*Rfor)

    if v(i+1) > Vmax
        f = warndlg('Se originó un derrape','Advertencia');
        break
    end

    a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;

    if x(i+1,1)>xf
        break
    end
end

plot(pista,x,y,'*','Color','cyan')
Ekd(1,1) = Ekn(end);
xd(1,1) = x(end);
yd(1,1) = y(end);
md = polyval(q,xd);
angd = atand(md);
Vd(1,1) = v(end);
app.CoeficientedefriccincinticoEditField_2.Value = 0.3;
muk = 0.3;
ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
vxd(1,1) = Vd(1,1)*cosd(angd);
vyd(1,1) = Vd(1,1)*sind(angd);
m2 = m*2;
app.Masaauto2kgEditField.Value = m2;
for i = 1 : 2000000
    xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
    yd(i+1,1) = yd(i,1) +vyd(i,1)*h;

    Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
    vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
    vyd(i+1,1) = Vd(i+1,1)*sind(angd);
    Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
    ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
    if Vd(i+1,1) <= 0
        break
    end
end


Eperdida = Ekd(end)-Eki;

plot(pista,xd,yd,'red','LineWidth',2)
carrox = 59.2217;
carroy = 275.173;
carro1 = [carrox carroy];
plot(pista,carrox, carroy,'MarkerSize',60,'MarkerFaceColor','black')
Rmin = min(R); % Radio de curvatura minimo
Rmax = max(R); % Radio de curvatura maximo

syms x % Se declara la variable x
% se define el polinomio que modela la zona de curvas
funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
funpolprima = diff(funpol); % Se deriva el polinomio
maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                   % y se despeja para x
vfd = Vd(3,1);
vautoschoque = ((m)/ (m+m2))*vfd;
app.Velocidadauto1msEditField.Value = vautoschoque;
app.Velocidadauto2msEditField.Value = vautoschoque;
app.Direccinauto2EditField.Value = "Derecha";
app.Direccinauto1EditField.Value = "Derecha";

app.ConstantederestitucinKEditField.Value = 0;
app.Vparaperder93deEMmsEditField.Value=0;
app.Vparaperder95deEMmsEditField.Value = 0;
app.Vparaperder98deEMmsEditField.Value =0;


        end

        % Button pushed function: ColisinelsticaconparmetrosdefaultButton
        function ColisinelsticaconparmetrosdefaultButtonPushed(app, event)
            pista = app.UIAxes2;
x1gradas = 10;
y1gradas = 170;
x2gradas = 280;
y2gradas = 120;

x3gradas = 130;
y3gradas = 200;
x4gradas = 230;
y4gradas = 50;

Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
Agradas = inv(Xgradas)*Ygradas;

xgradas = linspace(x1gradas,x2gradas,201);

fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);

dxgradas = [];
dygradas = [];
dsgradas = [];
der2xgradas = [];
der2ygradas =[];
Rgradas = [];
cgradas = [];

for i = 1:length(xgradas)-1
    dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
    dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
    dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
    der1xgradas = dxgradas./dsgradas;
    der1ygradas = dygradas./dsgradas; 
end


for i = 1:length(der1xgradas)-1
    der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
    der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
% Radio de curvatura
    Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
end

longitudgradas = sum(dsgradas);
Radmingradas = min(Rgradas);


for j = 2:length(der1ygradas)-1
    if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
        cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
    end
end

indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
R1gradas = Rgradas(indice1gradas);
R2gradas = Rgradas(indice2gradas);

t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));


m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
tangente1gradas = m1gradas*coorx1gradas + b1gradas;% REcta tangente al punto critico

m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
tangente2gradas = m2gradas*coorx2gradas + b2gradas;


perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);

paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
paralela2gradas = tangente2gradas-23;


hold(pista,"on")  
%plot(xgradas,fcurvagradas,'LineWidth',2)
plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
%plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
%plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)

plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)

punto1 = 60;
punto2 = 300;

punto3 = 4.15361;
punto4 = 243.7138;

punto5 = -4.224197604174;
punto6 = 251.2098386066039;

punto7 = 52.6567518574205;
punto8 = 307.6498504754727;

punto9= 168.5857808645159;
punto10 = 71.6697939358922;

punto11 = 227.7527419724195;
punto12 = 16.9324342207233;

punto13 = 220.44968256758;
punto14 = 9.435308434908;

punto15 = 161.346511139683;
punto16 = 64.7247973200413;

grada1 = [punto1 punto3];
grada2 = [punto2 punto4];

grada3 = [punto3 punto5];
grada4 = [punto4 punto6];

grada5 = [punto1 punto7];
grada6 = [punto2 punto8];

grada7 = [punto5 punto7];
grada8 = [punto6 punto8];

grada9 = [punto9 punto11];
grada10 = [punto10 punto12];

grada11 = [punto15 punto13];
grada12 = [punto16 punto14];

grada13 = [punto9 punto15];
grada14 = [punto10 punto16];

grada15 = [punto11 punto13];
grada16 = [punto12 punto14];


plot (pista,grada1, grada2,'g','LineWidth',2)
plot(pista,grada3, grada4,'g','LineWidth',2)
plot(pista,grada5, grada6,'g','LineWidth',2)
plot(pista,grada7, grada8,'g','LineWidth',2)
plot(pista,grada9, grada10,'g','LineWidth',2)
plot(pista,grada11, grada12,'g','LineWidth',2)
plot(pista,grada13, grada14,'g','LineWidth',2)
plot(pista,grada15, grada16,'g','LineWidth',2)

xi = 10 ; yi = 170;  % punto inicial de la curva
xf = 280 ; yf = 120; % punto final de la curva

xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva

p = polyfit(xr,yr,3); % ajuste de curva de grado 3
x1 = linspace(xi,xf);
f1 = polyval(p,x1);
% grafica y formato de la misma
plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')

grid(pista,"on") 
plot(pista,x1,f1,'r--', 'Color','blue')
daspect([1 1 1]);
title(pista,'Zona de curvas de la pista')
q = polyder(p); % derivada del polinomio que modela la zona de curvas
q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
pendiente(1,1) = polyval(q, xi);

f1d = polyval(q,xi:xf);
f2d = polyval(q2,xi:xf);

fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva

L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva

R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura


app.FuerzadelMotorNEditField.Value = 100;
Fm = 100;
app.MasadelautokgEditField.Value= 789;
m = 789;
app.reatransversaldelautomEditField_2.Value= 0.3;
At = 0.3;
app.CoeficientedearrastreEditField.Value= 0.85;
cd = 0.85;
app.CoeficientedefriccionestticoEditField.Value= 0.9;
mus = 0.9;
g = 9.8;
app.DensidaddelaireKgm3EditField.Value= 1.225;
Densidad = 1.225;
app.VelocidadinicialmsEditField_2.Value = 23.5;
v(1,1) = 23.5;
a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
ang(1,1) = atand(pendiente(1,1));
vx(1,1) = v(1,1)*cosd(ang(1,1));
vy(1,1) = v(1,1)*sind(ang(1,1));
x(1,1) = xi;
y(1,1) = yi;

Eki = (1/2)*m*(v(1,1)^2);
Ekn(1,1) = Eki; 

h = 0.01;

for i = 1:100000
    x(i+1,1) = x(i,1)+vx(i,1)*h;
    y(i+1,1) = y(i,1)+vy(i,1)*h;
   

    pendiente(i+1,1) = polyval(q,x(i+1));

    ang(i+1,1) = atand(pendiente(i+1,1));

    v(i+1,1) = v(i,1)+a(i,1)*h;
    vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
    vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
    Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
    f1dfor = polyval(q,x(i+1));
    f2dfor = polyval(q2,x(i+1));
    Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);

    Vmax = sqrt(mus*g*Rfor)

    if v(i+1) > Vmax
        f = warndlg('Se originó un derrape','Advertencia');
        break
    end

    a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;

    if x(i+1,1)>xf
        break
    end
end

plot(pista,x,y,'*','Color','cyan')
Ekd(1,1) = Ekn(end);
xd(1,1) = x(end);
yd(1,1) = y(end);
md = polyval(q,xd);
angd = atand(md);
Vd(1,1) = v(end);
app.CoeficientedefriccincinticoEditField_2.Value = 0.3;
muk = 0.3;
ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
vxd(1,1) = Vd(1,1)*cosd(angd);
vyd(1,1) = Vd(1,1)*sind(angd);
m2 = m*2;
app.Masaauto2kgEditField.Value = m2;
for i = 1 : 2000000
    xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
    yd(i+1,1) = yd(i,1) +vyd(i,1)*h;

    Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
    vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
    vyd(i+1,1) = Vd(i+1,1)*sind(angd);
    Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
    ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
    if Vd(i+1,1) <= 0
        break
    end
end


Eperdida = Ekd(end)-Eki;

plot(pista,xd,yd,'red','LineWidth',2)
carrox = 59.2217;
carroy = 275.173;
carro1 = [carrox carroy];
plot(pista,carrox, carroy,'MarkerSize',60,'MarkerFaceColor','black')
Rmin = min(R); % Radio de curvatura minimo
Rmax = max(R); % Radio de curvatura maximo

syms x % Se declara la variable x
% se define el polinomio que modela la zona de curvas
funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
funpolprima = diff(funpol); % Se deriva el polinomio
maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                   % y se despeja para x

vfd = Vd(3,1);
vauto1choque = ((m-m2)/(m+m2))*vfd;
vauto2choque = ((2*m)/(m+m2))*vfd;


app.Velocidadauto1msEditField.Value = vauto1choque;
app.Velocidadauto2msEditField.Value = vauto2choque;
app.Direccinauto2EditField.Value = "Derecha";
app.Direccinauto1EditField.Value = "Izquierda";
app.ConstantederestitucinKEditField.Value = 0;
app.Vparaperder93deEMmsEditField.Value=0;
app.Vparaperder95deEMmsEditField.Value = 0;
app.Vparaperder98deEMmsEditField.Value =0;

        end

        % Button pushed function: ColisingradasconparmetrosdefaultButton
        function ColisingradasconparmetrosdefaultButtonPushed(app, event)
            pista = app.UIAxes2;
x1gradas = 10;
y1gradas = 170;
x2gradas = 280;
y2gradas = 120;

x3gradas = 130;
y3gradas = 200;
x4gradas = 230;
y4gradas = 50;

Ygradas = [y1gradas; y2gradas; y3gradas; y4gradas];
Xgradas = [x1gradas^3 x1gradas^2 x1gradas 1; x2gradas^3 x2gradas^2 x2gradas 1; x3gradas^3 x3gradas^2 x3gradas 1; x4gradas^3 x4gradas^2 x4gradas 1];
Agradas = inv(Xgradas)*Ygradas;

xgradas = linspace(x1gradas,x2gradas,201);

fcurvagradas = Agradas(1)*xgradas.^3 + Agradas(2)*xgradas.^2 +Agradas(3)*xgradas +Agradas(4);

dxgradas = [];
dygradas = [];
dsgradas = [];
der2xgradas = [];
der2ygradas =[];
Rgradas = [];
cgradas = [];

for i = 1:length(xgradas)-1
    dxgradas = [dxgradas,xgradas(i+1)-xgradas(i)];
    dygradas = [dygradas,fcurvagradas(i+1)-fcurvagradas(i)];
    dsgradas = [dsgradas,sqrt(dxgradas(i)^2 + dygradas(i)^2)];
    der1xgradas = dxgradas./dsgradas;
    der1ygradas = dygradas./dsgradas; 
end


for i = 1:length(der1xgradas)-1
    der2xgradas = [der2xgradas, (der1xgradas(i+1)-der1xgradas(i))/dsgradas(i)];
    der2ygradas = [der2ygradas, (der1ygradas(i+1)-der1ygradas(i))/dsgradas(i)];
% Radio de curvatura
    Rgradas = [Rgradas,1/sqrt(der2xgradas(i).^2+der2ygradas(i).^2)];
end

longitudgradas = sum(dsgradas);
Radmingradas = min(Rgradas);


for j = 2:length(der1ygradas)-1
    if (der1ygradas(j)>0 && der1ygradas(j-1)<0) | (der1ygradas(j)>0 && der1ygradas(j+1)<0)
        cgradas=[cgradas,j];% Guarda los índices donde esto ocurre
    end
end

indice1gradas = find(Rgradas==min(Rgradas(cgradas(1)-5:cgradas(1)+5)));
indice2gradas = find(Rgradas==min(Rgradas(cgradas(2)-5:cgradas(2)+5)));
R1gradas = Rgradas(indice1gradas);
R2gradas = Rgradas(indice2gradas);

t1gradas=find(xgradas>(xgradas(indice1gradas)-R1gradas-0.6) & xgradas<(xgradas(indice1gradas)-R1gradas+0.6));% Este debe ser solo un índice
t2gradas=find(xgradas>(xgradas(indice2gradas)-R2gradas-0.6) & xgradas<(xgradas(indice2gradas)-R2gradas+0.6));


m1gradas = der1ygradas(t1gradas)/der1xgradas(t1gradas);
b1gradas = fcurvagradas(t1gradas) - m1gradas*xgradas(t1gradas); % Punto de corte
coorx1gradas = [xgradas(t1gradas-26):xgradas(t1gradas+20)];
tangente1gradas = m1gradas*coorx1gradas + b1gradas;% REcta tangente al punto critico

m2gradas = der1ygradas(t2gradas)/der1xgradas(t2gradas);
b2gradas = fcurvagradas(t2gradas) - m2gradas*xgradas(t2gradas); % Punto de corte
coorx2gradas = [xgradas(t2gradas-26):xgradas(t2gradas+20)];
tangente2gradas = m2gradas*coorx2gradas + b2gradas;


perpendicular1gradas = -(1/m1gradas)*coorx1gradas + (fcurvagradas(t1gradas)+xgradas(t1gradas)/m1gradas);
perpendicular2gradas = -(1/m2gradas)*coorx2gradas + (fcurvagradas(t2gradas)+xgradas(t2gradas)/m2gradas);

paralela1gradas = tangente1gradas+23;% Recta a 20 unidades de la recta tangente al punto critico
paralela2gradas = tangente2gradas-23;


hold(pista,"on")  
%plot(xgradas,fcurvagradas,'LineWidth',2)
plot(pista,coorx1gradas,tangente1gradas,'black','LineWidth',2)
plot(pista,coorx2gradas,tangente2gradas,'black','LineWidth',2)
%plot(coorx1gradas,paralela1gradas,'g','LineWidth',2)
%plot(coorx2gradas,paralela2gradas,'g','LineWidth',2)
plot(pista,coorx1gradas,perpendicular1gradas,'black','LineWidth',2)
plot(pista,coorx2gradas,perpendicular2gradas,'black','LineWidth',2)

plot(pista,xgradas(t1gradas),fcurvagradas(t1gradas),'o','MarkerFaceColor','red','MarkerSize',6)
plot(pista,xgradas(t2gradas),fcurvagradas(t2gradas),'o','MarkerFaceColor','red','MarkerSize',6)

punto1 = 60;
punto2 = 300;

punto3 = 4.15361;
punto4 = 243.7138;

punto5 = -4.224197604174;
punto6 = 251.2098386066039;

punto7 = 52.6567518574205;
punto8 = 307.6498504754727;

punto9= 168.5857808645159;
punto10 = 71.6697939358922;

punto11 = 227.7527419724195;
punto12 = 16.9324342207233;

punto13 = 220.44968256758;
punto14 = 9.435308434908;

punto15 = 161.346511139683;
punto16 = 64.7247973200413;

grada1 = [punto1 punto3];
grada2 = [punto2 punto4];

grada3 = [punto3 punto5];
grada4 = [punto4 punto6];

grada5 = [punto1 punto7];
grada6 = [punto2 punto8];

grada7 = [punto5 punto7];
grada8 = [punto6 punto8];

grada9 = [punto9 punto11];
grada10 = [punto10 punto12];

grada11 = [punto15 punto13];
grada12 = [punto16 punto14];

grada13 = [punto9 punto15];
grada14 = [punto10 punto16];

grada15 = [punto11 punto13];
grada16 = [punto12 punto14];


plot (pista,grada1, grada2,'g','LineWidth',2)
plot(pista,grada3, grada4,'g','LineWidth',2)
plot(pista,grada5, grada6,'g','LineWidth',2)
plot(pista,grada7, grada8,'g','LineWidth',2)
plot(pista,grada9, grada10,'g','LineWidth',2)
plot(pista,grada11, grada12,'g','LineWidth',2)
plot(pista,grada13, grada14,'g','LineWidth',2)
plot(pista,grada15, grada16,'g','LineWidth',2)

xi = 10 ; yi = 170;  % punto inicial de la curva
xf = 280 ; yf = 120; % punto final de la curva

xr = [xi;130;230;xf]; % puntos en x por donde debe pasar la curva
yr = [yi;200;50;yf]; % puntos en y por donde debe pasar la curva

p = polyfit(xr,yr,3); % ajuste de curva de grado 3
x1 = linspace(xi,xf);
f1 = polyval(p,x1);
% grafica y formato de la misma
plot(pista,xr,yr,'hexagram', 'Color', 'black','MarkerSize',8,'MarkerFaceColor','black')

grid(pista,"on") 
plot(pista,x1,f1,'r--', 'Color','blue')
daspect([1 1 1]);
title(pista,'Zona de curvas de la pista')
q = polyder(p); % derivada del polinomio que modela la zona de curvas
q2 = polyder(q); % segunda derivada del polinomio que modela la zona de curvas
pendiente(1,1) = polyval(q, xi);

f1d = polyval(q,xi:xf);
f2d = polyval(q2,xi:xf);

fun = @(xr) sqrt(1+(q(1)*xr.^2+q(2)*xr+q(3)).^2); % formula sin integrar de la longitud de la curva

L = integral(fun, xi, xf); % Integración de la formula para la longitud de la curva

R = ((1+f1d.^2).^(3/2))./abs(f2d); % formula para el radio de curvatura


app.FuerzadelMotorNEditField.Value = 100;
Fm = 100;
app.MasadelautokgEditField.Value= 789;
m = 789;
app.reatransversaldelautomEditField_2.Value= 0.3;
At = 0.3;
app.CoeficientedearrastreEditField.Value= 0.85;
cd = 0.85;
app.CoeficientedefriccionestticoEditField.Value= 0.9;
mus = 0.9;
g = 9.8;
app.DensidaddelaireKgm3EditField.Value= 1.225;
Densidad = 1.225;
app.VelocidadinicialmsEditField_2.Value = 23.5;
v(1,1) = 23.5;
a(1,1) = (Fm-(1/2)*Densidad*At*cd*v(1,1)^2)/m;
ang(1,1) = atand(pendiente(1,1));
vx(1,1) = v(1,1)*cosd(ang(1,1));
vy(1,1) = v(1,1)*sind(ang(1,1));
x(1,1) = xi;
y(1,1) = yi;

Eki = (1/2)*m*(v(1,1)^2);
Ekn(1,1) = Eki; 

h = 0.01;

for i = 1:100000
    x(i+1,1) = x(i,1)+vx(i,1)*h;
    y(i+1,1) = y(i,1)+vy(i,1)*h;
   

    pendiente(i+1,1) = polyval(q,x(i+1));

    ang(i+1,1) = atand(pendiente(i+1,1));

    v(i+1,1) = v(i,1)+a(i,1)*h;
    vx(i+1,1) = v(i+1,1)*cosd(ang(i+1,1));
    vy(i+1,1) = v(i+1,1)*sind(ang(i+1,1));
    Ekn(i+1,1) = (1/2)*m*(v(i+1,1)^2);
    f1dfor = polyval(q,x(i+1));
    f2dfor = polyval(q2,x(i+1));
    Rfor = ((1+f1dfor^2)^(3/2))/abs(f2dfor);

    Vmax = sqrt(mus*g*Rfor)

    if v(i+1) > Vmax
        f = warndlg('Se originó un derrape','Advertencia');
        break
    end

    a(i+1,1) = (Fm-(1/2)*Densidad*At*cd*v(i+1,1)^2)/m;

    if x(i+1,1)>xf
        break
    end
end

plot(pista,x,y,'*','Color','cyan')
Ekd(1,1) = Ekn(end);
xd(1,1) = x(end);
yd(1,1) = y(end);
md = polyval(q,xd);
angd = atand(md);
Vd(1,1) = v(end);
app.CoeficientedefriccincinticoEditField_2.Value = 0.3;
muk = 0.3;
ad(1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(1,1)^2)/m;
vxd(1,1) = Vd(1,1)*cosd(angd);
vyd(1,1) = Vd(1,1)*sind(angd);
m2 = m*2;
app.Masaauto2kgEditField.Value = m2;
for i = 1 : 2000000
    xd(i+1,1) = xd(i,1) +vxd(i,1)*h;
    yd(i+1,1) = yd(i,1) +vyd(i,1)*h;

    Vd(i+1,1) = Vd(i,1)+ad(i,1)*h;
    vxd(i+1,1) = Vd(i+1,1)*cosd(angd);
    vyd(i+1,1) = Vd(i+1,1)*sind(angd);
    Ekd(i+1,1) = (1/2)*m*(Vd(i+1,1)^2);
    ad(i+1,1) = (-muk*m*g-(1/2)*Densidad*At*cd*Vd(i+1,1)^2)/m;
    if Vd(i+1,1) <= 0
        break
    end
end


Eperdida = Ekd(end)-Eki;

plot(pista,xd,yd,'red','LineWidth',2)
carrox = 59.2217;
carroy = 275.173;
carro1 = [carrox carroy];
plot(pista,carrox, carroy,'MarkerSize',60,'MarkerFaceColor','black')
Rmin = min(R); % Radio de curvatura minimo
Rmax = max(R); % Radio de curvatura maximo

syms x % Se declara la variable x
% se define el polinomio que modela la zona de curvas
funpol = ((1801*x^3)/17820000)-((20203*x^2)/445500)+((169267*x)/35640)+(565519/4455);
funpolprima = diff(funpol); % Se deriva el polinomio
maxmins = solve(funpolprima==0,x); % Se iguala el resultado de la derivacion a 0
                                   % y se despeja para x

vfd = Vd(3,1);
vchoquegradas98 = (2 * 0.02 * (0.5*vfd));
vchoquegradas95 = (2 * 0.05 * (0.5*vfd));
vchoquegradas93 = (2 * 0.07 * (0.5*vfd));
constrest = (m * vfd^2) / 4;
app.Vparaperder98deEMmsEditField.Value =vchoquegradas98 ;
app.Vparaperder95deEMmsEditField.Value =vchoquegradas95 ;
app.Vparaperder93deEMmsEditField.Value =vchoquegradas93 ;
app.Velocidadauto1msEditField.Value = 0;
app.Velocidadauto2msEditField.Value = 0;
app.Direccinauto1EditField.Value = "N/A";
app.Direccinauto2EditField.Value = "N/A";
app.ConstantederestitucinKEditField.Value = constrest;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BienvenidaTab = uitab(app.TabGroup);
            app.BienvenidaTab.Title = 'Bienvenida';
            app.Image = uiimage(app.BienvenidaTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '25F5LXF2LFAWHFZCSFDDTCJH7E.jpg');
            app.Label = uilabel(app.BienvenidaTab);
            app.Label.Text = 'Simulación computacional de energía perdida en un circuito de Fórmula Uno';
            app.Label_2 = uilabel(app.BienvenidaTab);
            app.Label_2.Text = '¡Bienvenido!, en este simulador tendrás la oportunidad de explorar los diferentes factores que se ven involucrados en una carrera de fórmula 1 a través de principios físicos de conservación de energía. El simulador ofrece la oportunidad de modificar los parámetros a los que se somete un auto de formula 1 al momento de recorre una zona de curvas por medio del análisis de su trayectoria. En el simulador se puede estudiar la trayectoria del auto con y sin derrapes, y además se puede analizar posibles colisiones que este auto puede sufrir. Ambas partes del simulador ofrecen la oportunidad de observar los cambios de energía dentro del sistema. ';
            app.Image8 = uiimage(app.BienvenidaTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.ConocelapistaTab = uitab(app.TabGroup);
            app.ConocelapistaTab.Title = 'Conoce la pista';
            app.ZonadecurvasquesemanjeaLabel = uilabel(app.ConocelapistaTab);
            app.ZonadecurvasquesemanjeaLabel.Text = 'Zona de curvas que se manjea';
            app.Image2 = uiimage(app.ConocelapistaTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2022-11-27 at 12.56.13 PM.jpeg');
            app.Label_3 = uilabel(app.ConocelapistaTab);
            app.Label_3.Text = 'Basándonos en 4 puntos de un plano cartesiano, se realizó una interpolación de Lagrange para generar un polinomio de grado 3. Este polinomio sirve como modelo para la zona de curvas. El polinomio pasa por los puntos (10, 170) (280, 120) (130, 200) (230, 50) y la ecuación que modela la curva es: ';
            app.Image3 = uiimage(app.ConocelapistaTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla_20221127_112722.png');
            app.Label_4 = uilabel(app.ConocelapistaTab);
            app.Label_4.Text = 'En el diagrama también se observan los puntos A, B, C y D, estos son los puntos previamente mencionados. Los puntos K y E son los puntos de derrape de la zona de curvas, donde el radio de curvatura es mínimo. Por seguridad, las gradas (rectángulos azules) se han colocado paralelas a los puntos de derrape con una separación de 20 m. ';
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.InstruccionesdelsimuladorLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesdelsimuladorLabel.Text = 'Instrucciones del simulador';
            app.Image4 = uiimage(app.InstruccionesTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'razon-fernando-alonso-acertado-fichando-aston-martin-f1-202291410-1669277836_4_720x440.jpg');
            app.Image5 = uiimage(app.InstruccionesTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '180827152051-spa-crash.jpg');
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = 'En las pestañas de derrapes y colisiones se podrá analizar a los autos de formula 1 en diferentes escenarios para así visualizar los cambios de energía dentro del sistema bajo diversas condiciones. Cada pestaña posee una serie de campos donde es posible modificar valores numéricos de factores involucrados en la simulación. Por default, cada pestaña tiene una serie de valores predeterminados que se basan en datos de la vida real, pero si se desea, es posible modificar estos valores si se le indica el simulador por medio del botón señalado para dicha acción. Cada pestaña tiene varios botones para correr la simulación, cada uno de estos generará lo que si etiqueta tenga escrito y posteriormente se generará una gráfica basada en la opción seleccionada. ';
            app.Label_8 = uilabel(app.InstruccionesTab);
            app.Label_8.Text = 'Simbología: Al correr la simulación, de graficará la trayectoria del auto, la cual estará denotada por el color cyan. La pista estará denotada por una línea punteada color azul. En caso de que el auto derrape, su trayectoria estará señalada por una línea roja. Los puntos de derrape son puntos rojos, las líneas tangentes, perpendiculares y paralelas a los puntos de derrape son color negro y por último, las línas verdes representan las gradas.';
            app.DerrapesTab = uitab(app.TabGroup);
            app.DerrapesTab.Title = 'Derrapes';
            app.UIAxes = uiaxes(app.DerrapesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_6 = uilabel(app.DerrapesTab);
            app.Label_6.Text = 'Simulación computacional de energía perdida en un circuito de Fórmula Uno (Derrapes)';
            app.FuerzadelmotorNEditFieldLabel = uilabel(app.DerrapesTab);
            app.FuerzadelmotorNEditFieldLabel.Text = 'Fuerza del motor (N)';
            app.FuerzadelmotorNEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.MasadelautoKgEditFieldLabel = uilabel(app.DerrapesTab);
            app.MasadelautoKgEditFieldLabel.Text = 'Masa del auto (Kg)';
            app.MasadelautoKgEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.reatransversaldelautomEditFieldLabel = uilabel(app.DerrapesTab);
            app.reatransversaldelautomEditFieldLabel.Text = 'Área transversal del auto (m)';
            app.reatransversaldelautomEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.CoeficientedeArrastreEditFieldLabel = uilabel(app.DerrapesTab);
            app.CoeficientedeArrastreEditFieldLabel.Text = 'Coeficiente de Arrastre';
            app.CoeficientedeArrastreEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.CoeficientedefriccnestticoEditFieldLabel = uilabel(app.DerrapesTab);
            app.CoeficientedefriccnestticoEditFieldLabel.Text = 'Coeficiente de friccón estático';
            app.CoeficientedefriccnestticoEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.Densidadvolumtricadelairekgm3EditFieldLabel = uilabel(app.DerrapesTab);
            app.Densidadvolumtricadelairekgm3EditFieldLabel.Text = 'Densidad volumétrica del aire (kg/m^3)';
            app.Densidadvolumtricadelairekgm3EditField = uieditfield(app.DerrapesTab, 'numeric');
            app.VelocidadinicialmsEditFieldLabel = uilabel(app.DerrapesTab);
            app.VelocidadinicialmsEditFieldLabel.Text = 'Velocidad inicial (m/s)';
            app.VelocidadinicialmsEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.CoeficientedefriccincinticoEditFieldLabel = uilabel(app.DerrapesTab);
            app.CoeficientedefriccincinticoEditFieldLabel.Text = 'Coeficiente de fricción cinético';
            app.CoeficientedefriccincinticoEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.Aceleracinms2EditFieldLabel = uilabel(app.DerrapesTab);
            app.Aceleracinms2EditFieldLabel.Text = 'Aceleración (m/s^2)';
            app.Aceleracinms2EditField = uieditfield(app.DerrapesTab, 'numeric');
            app.EnergacinticainicialJEditFieldLabel = uilabel(app.DerrapesTab);
            app.EnergacinticainicialJEditFieldLabel.Text = 'Energía cinética inicial (J)';
            app.EnergacinticainicialJEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.EnergacinticaenlacurvaJEditFieldLabel = uilabel(app.DerrapesTab);
            app.EnergacinticaenlacurvaJEditFieldLabel.Text = 'Energía cinética en la curva (J)';
            app.EnergacinticaenlacurvaJEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.EnergacinticaalderraparJEditFieldLabel = uilabel(app.DerrapesTab);
            app.EnergacinticaalderraparJEditFieldLabel.Text = 'Energía cinética al derrapar (J)';
            app.EnergacinticaalderraparJEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.RadiodecurvaturamnimomEditFieldLabel = uilabel(app.DerrapesTab);
            app.RadiodecurvaturamnimomEditFieldLabel.Text = 'Radio de curvatura mínimo (m)';
            app.RadiodecurvaturamnimomEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.LonituddelacurvamEditFieldLabel = uilabel(app.DerrapesTab);
            app.LonituddelacurvamEditFieldLabel.Text = 'Lonitud de la curva (m)';
            app.LonituddelacurvamEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.Image6 = uiimage(app.DerrapesTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '55299.png');
            app.UsarvaloresdefaultNohayderrapeButton = uibutton(app.DerrapesTab, 'push');
            app.UsarvaloresdefaultNohayderrapeButton.ButtonPushedFcn = createCallbackFcn(app, @UsarvaloresdefaultNohayderrapeButtonPushed, true);
            app.UsarvaloresdefaultNohayderrapeButton.Text = 'Usar valores default (No hay derrape)';
            app.UsarvalorespersonalizadosButton = uibutton(app.DerrapesTab, 'push');
            app.UsarvalorespersonalizadosButton.ButtonPushedFcn = createCallbackFcn(app, @UsarvalorespersonalizadosButtonPushed, true);
            app.UsarvalorespersonalizadosButton.Text = 'Usar valores personalizados';
            app.VelocidadmsEditFieldLabel = uilabel(app.DerrapesTab);
            app.VelocidadmsEditFieldLabel.Text = 'Velocidad (m/s)';
            app.VelocidadmsEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.CorrersimulacinButton = uibutton(app.DerrapesTab, 'push');
            app.CorrersimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @CorrersimulacinButtonPushed, true);
            app.CorrersimulacinButton.Text = 'Correr simulación';
            app.DerrapeenlaprimerazonaCFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlaprimerazonaCFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlaprimerazonaCFButtonPushed, true);
            app.DerrapeenlaprimerazonaCFButton.Text = 'Derrape en la primera zona (CF)';
            app.SemodificarnlosvaloresinicialesLabel = uilabel(app.DerrapesTab);
            app.SemodificarnlosvaloresinicialesLabel.Text = 'Se modificarán los valores iniciales y se correrá la simulación en automático';
            app.DerrapeenlatercerazonaCFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlatercerazonaCFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlatercerazonaCFButtonPushed, true);
            app.DerrapeenlatercerazonaCFButton.Text = 'Derrape en la tercera zona (CF)';
            app.DerrapeenlasegundazonaCFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlasegundazonaCFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlasegundazonaCFButtonPushed, true);
            app.DerrapeenlasegundazonaCFButton.Text = 'Derrape en la segunda zona (CF)';
            app.CFAplcandofrenosSFSinaplicarfrenosLabel = uilabel(app.DerrapesTab);
            app.CFAplcandofrenosSFSinaplicarfrenosLabel.Text = 'CF = Aplcando frenos     SF = Sin aplicar frenos';
            app.EnergaperdidaalderraparJEditFieldLabel = uilabel(app.DerrapesTab);
            app.EnergaperdidaalderraparJEditFieldLabel.Text = 'Energía perdida al derrapar (J)';
            app.EnergaperdidaalderraparJEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.DerrapeenlaprimerazonaSFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlaprimerazonaSFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlaprimerazonaSFButtonPushed, true);
            app.DerrapeenlaprimerazonaSFButton.Text = 'Derrape en la primera zona (SF)';
            app.DerrapeenlasegundazonaSFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlasegundazonaSFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlasegundazonaSFButtonPushed, true);
            app.DerrapeenlasegundazonaSFButton.Text = 'Derrape en la segunda zona (SF)';
            app.DerrapeenlatercerazonaSFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlatercerazonaSFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlatercerazonaSFButtonPushed, true);
            app.DerrapeenlatercerazonaSFButton.Text = 'Derrape en la tercera zona (SF)';
            app.SemodificarnlosvaloresinicialesLabel_2 = uilabel(app.DerrapesTab);
            app.SemodificarnlosvaloresinicialesLabel_2.Text = 'Con los siguientes botones:';
            app.ngulodelperlategradosEditFieldLabel = uilabel(app.DerrapesTab);
            app.ngulodelperlategradosEditFieldLabel.Text = 'Ángulo del perlate (grados)';
            app.ngulodelperlategradosEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.VmaxmsEditFieldLabel = uilabel(app.DerrapesTab);
            app.VmaxmsEditFieldLabel.Text = 'V max (m/s)';
            app.VmaxmsEditField = uieditfield(app.DerrapesTab, 'numeric');
            app.DerrapeenlacuartazonaCFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlacuartazonaCFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlacuartazonaCFButtonPushed, true);
            app.DerrapeenlacuartazonaCFButton.Text = 'Derrape en la cuarta zona (CF)';
            app.DerrapeenlacuartazonaSFButton = uibutton(app.DerrapesTab, 'push');
            app.DerrapeenlacuartazonaSFButton.ButtonPushedFcn = createCallbackFcn(app, @DerrapeenlacuartazonaSFButtonPushed, true);
            app.DerrapeenlacuartazonaSFButton.Text = 'Derrape en la cuarta zona (SF)';
            app.ColisionesTab = uitab(app.TabGroup);
            app.ColisionesTab.Title = 'Colisiones';
            app.UIAxes2 = uiaxes(app.ColisionesTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label_7 = uilabel(app.ColisionesTab);
            app.Label_7.Text = 'Simulación computacional de energía perdida en un circuito de Fórmula Uno (Colisiones)';
            app.Image7 = uiimage(app.ColisionesTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, '55299.png');
            app.FuerzadelMotorNEditFieldLabel = uilabel(app.ColisionesTab);
            app.FuerzadelMotorNEditFieldLabel.Text = 'Fuerza del Motor (N)';
            app.FuerzadelMotorNEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.MasadelautokgEditFieldLabel = uilabel(app.ColisionesTab);
            app.MasadelautokgEditFieldLabel.Text = 'Masa del auto (kg)';
            app.MasadelautokgEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.reatransversaldelautomEditField_2Label = uilabel(app.ColisionesTab);
            app.reatransversaldelautomEditField_2Label.Text = 'Área transversal del auto (m)';
            app.reatransversaldelautomEditField_2 = uieditfield(app.ColisionesTab, 'numeric');
            app.CoeficientedearrastreEditFieldLabel = uilabel(app.ColisionesTab);
            app.CoeficientedearrastreEditFieldLabel.Text = 'Coeficiente de arrastre';
            app.CoeficientedearrastreEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.CoeficientedefriccionestticoEditFieldLabel = uilabel(app.ColisionesTab);
            app.CoeficientedefriccionestticoEditFieldLabel.Text = 'Coeficiente de friccion estático';
            app.CoeficientedefriccionestticoEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.DensidaddelaireKgm3EditFieldLabel = uilabel(app.ColisionesTab);
            app.DensidaddelaireKgm3EditFieldLabel.Text = 'Densidad del aire (Kg/m^3)';
            app.DensidaddelaireKgm3EditField = uieditfield(app.ColisionesTab, 'numeric');
            app.VelocidadinicialmsEditField_2Label = uilabel(app.ColisionesTab);
            app.VelocidadinicialmsEditField_2Label.Text = 'Velocidad inicial (m/s)';
            app.VelocidadinicialmsEditField_2 = uieditfield(app.ColisionesTab, 'numeric');
            app.CoeficientedefriccincinticoEditField_2Label = uilabel(app.ColisionesTab);
            app.CoeficientedefriccincinticoEditField_2Label.Text = 'Coeficiente de fricción cinético';
            app.CoeficientedefriccincinticoEditField_2 = uieditfield(app.ColisionesTab, 'numeric');
            app.Masaauto2kgEditFieldLabel = uilabel(app.ColisionesTab);
            app.Masaauto2kgEditFieldLabel.Text = 'Masa auto 2 (kg)';
            app.Masaauto2kgEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.MasagradasEditFieldLabel = uilabel(app.ColisionesTab);
            app.MasagradasEditFieldLabel.Text = 'Masa gradas';
            app.MasagradasEditField = uieditfield(app.ColisionesTab, 'text');
            app.MasagradasEditField.Value = '>> Masa auto';
            app.Velocidadinicialauto2msEditFieldLabel = uilabel(app.ColisionesTab);
            app.Velocidadinicialauto2msEditFieldLabel.Text = 'Velocidad inicial auto 2 (m/s)';
            app.Velocidadinicialauto2msEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.ColisinperfectamenteinelsticaconparametrosdefaultButton = uibutton(app.ColisionesTab, 'push');
            app.ColisinperfectamenteinelsticaconparametrosdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ColisinperfectamenteinelsticaconparametrosdefaultButtonPushed, true);
            app.ColisinperfectamenteinelsticaconparametrosdefaultButton.Text = 'Colisión perfectamente inelástica con parametros default';
            app.ColisinelsticaconparmetrosdefaultButton = uibutton(app.ColisionesTab, 'push');
            app.ColisinelsticaconparmetrosdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ColisinelsticaconparmetrosdefaultButtonPushed, true);
            app.ColisinelsticaconparmetrosdefaultButton.Text = 'Colisión elástica con parámetros default';
            app.ColisingradasconparmetrosdefaultButton = uibutton(app.ColisionesTab, 'push');
            app.ColisingradasconparmetrosdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ColisingradasconparmetrosdefaultButtonPushed, true);
            app.ColisingradasconparmetrosdefaultButton.Text = 'Colisión gradas con parámetros default';
            app.ColisinconautoLabel = uilabel(app.ColisionesTab);
            app.ColisinconautoLabel.Text = 'Colisión con auto ';
            app.Velocidadauto1msEditFieldLabel = uilabel(app.ColisionesTab);
            app.Velocidadauto1msEditFieldLabel.Text = 'Velocidad auto 1 (m/s)';
            app.Velocidadauto1msEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.Velocidadauto2msEditFieldLabel = uilabel(app.ColisionesTab);
            app.Velocidadauto2msEditFieldLabel.Text = 'Velocidad auto 2 (m/s)';
            app.Velocidadauto2msEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.ColisincongradaLabel = uilabel(app.ColisionesTab);
            app.ColisincongradaLabel.Text = 'Colisión con grada';
            app.Vparaperder98deEMmsEditFieldLabel = uilabel(app.ColisionesTab);
            app.Vparaperder98deEMmsEditFieldLabel.Text = 'V para perder 98% de EM (m/s)';
            app.Vparaperder98deEMmsEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.Vparaperder95deEMmsEditFieldLabel = uilabel(app.ColisionesTab);
            app.Vparaperder95deEMmsEditFieldLabel.Text = 'V para perder 95% de EM (m/s)';
            app.Vparaperder95deEMmsEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.Vparaperder93deEMmsLabel = uilabel(app.ColisionesTab);
            app.Vparaperder93deEMmsLabel.Text = 'V para perder 93% de EM (m/s)';
            app.Vparaperder93deEMmsEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.Resortedecompresinde2mLabel = uilabel(app.ColisionesTab);
            app.Resortedecompresinde2mLabel.Text = 'Resorte de compresión de 2 m';
            app.ConstantederestitucinKEditFieldLabel = uilabel(app.ColisionesTab);
            app.ConstantederestitucinKEditFieldLabel.Text = 'Constante de restitución K';
            app.ConstantederestitucinKEditField = uieditfield(app.ColisionesTab, 'numeric');
            app.Direccinauto1EditFieldLabel = uilabel(app.ColisionesTab);
            app.Direccinauto1EditFieldLabel.Text = 'Dirección auto 1';
            app.Direccinauto1EditField = uieditfield(app.ColisionesTab, 'text');
            app.Direccinauto2EditFieldLabel = uilabel(app.ColisionesTab);
            app.Direccinauto2EditFieldLabel.Text = 'Dirección auto 2';
            app.Direccinauto2EditField = uieditfield(app.ColisionesTab, 'text');
            app.HallazgosTab = uitab(app.TabGroup);
            app.HallazgosTab.Title = 'Hallazgos';
            app.Valoresmnimosparaderrapezona1Label = uilabel(app.HallazgosTab);
            app.Valoresmnimosparaderrapezona1Label.Text = 'Valores mínimos para derrape (zona 1)';
            app.Valoresmnimosparaderrapezona3Label = uilabel(app.HallazgosTab);
            app.Valoresmnimosparaderrapezona3Label.Text = 'Valores mínimos para derrape (zona 3)';
            app.Valoresmnimosparaderrapezona2Label = uilabel(app.HallazgosTab);
            app.Valoresmnimosparaderrapezona2Label.Text = 'Valores mínimos para derrape (zona 2)';
            app.Valoresmnimosparaderrapezona4Label = uilabel(app.HallazgosTab);
            app.Valoresmnimosparaderrapezona4Label.Text = 'Valores mínimos para derrape (zona 4)';
            app.VelocidadmsLabel = uilabel(app.HallazgosTab);
            app.VelocidadmsLabel.Text = 'Velocidad (m/s)';
            app.ams2Label = uilabel(app.HallazgosTab);
            app.ams2Label.Text = 'a (m/s^2)';
            app.PeraltegradosLabel = uilabel(app.HallazgosTab);
            app.PeraltegradosLabel.Text = 'Peralte (grados)';
            app.Label_9 = uilabel(app.HallazgosTab);
            app.Label_9.Text = '23.5';
            app.Label_10 = uilabel(app.HallazgosTab);
            app.Label_10.Text = '12.4';
            app.Label_11 = uilabel(app.HallazgosTab);
            app.Label_11.Text = '12.36';
            app.Label_12 = uilabel(app.HallazgosTab);
            app.Label_12.Text = '9.71';
            app.Label_13 = uilabel(app.HallazgosTab);
            app.Label_13.Text = '0.0168';
            app.Label_14 = uilabel(app.HallazgosTab);
            app.Label_14.Text = '0.0918';
            app.Label_15 = uilabel(app.HallazgosTab);
            app.Label_15.Text = '0.0823';
            app.Label_16 = uilabel(app.HallazgosTab);
            app.Label_16.Text = '0.0919';
            app.Label_17 = uilabel(app.HallazgosTab);
            app.Label_17.Text = '50.65';
            app.Label_18 = uilabel(app.HallazgosTab);
            app.Label_18.Text = '6.7';
            app.Label_19 = uilabel(app.HallazgosTab);
            app.Label_19.Text = '-17.47';
            app.Label_20 = uilabel(app.HallazgosTab);
            app.Label_20.Text = '4.26';
            app.HALLAZGOSDELASIMULACINLabel = uilabel(app.HallazgosTab);
            app.HALLAZGOSDELASIMULACINLabel.Text = 'HALLAZGOS DE LA SIMULACIÓN';
            app.EnergaperdidaJLabel = uilabel(app.HallazgosTab);
            app.EnergaperdidaJLabel.Text = 'Energía perdida (J) ';
            app.Label_21 = uilabel(app.HallazgosTab);
            app.Label_21.Text = '-2.179*10^5';
            app.Label_22 = uilabel(app.HallazgosTab);
            app.Label_22.Text = '-6.066*10^4';
            app.Label_23 = uilabel(app.HallazgosTab);
            app.Label_23.Text = '-6.03*10^4';
            app.Label_24 = uilabel(app.HallazgosTab);
            app.Label_24.Text = '-3.719*10^4';
            app.ColisionesLabel = uilabel(app.HallazgosTab);
            app.ColisionesLabel.Text = 'Colisiones';
            app.Vparaperder982304msLabel = uilabel(app.HallazgosTab);
            app.Vparaperder982304msLabel.Text = 'V para perder 98% = 23.04 m/s';
            app.Vparaperder952233msLabel = uilabel(app.HallazgosTab);
            app.Vparaperder952233msLabel.Text = 'V para perder 95% = 22.33 m/s';
            app.Vparaperder932186msLabel = uilabel(app.HallazgosTab);
            app.Vparaperder932186msLabel.Text = 'V para perder 93% = 21.86 m/s';
            app.ConstantederesitucinK109105Label = uilabel(app.HallazgosTab);
            app.ConstantederesitucinK109105Label.Text = 'Constante de resitución K = 1.09 * 10^5';
            app.ChoquePerfectamenteinlasticoLabel = uilabel(app.HallazgosTab);
            app.ChoquePerfectamenteinlasticoLabel.Text = 'Choque Perfectamente inélastico';
            app.ChoquecongradasLabel = uilabel(app.HallazgosTab);
            app.ChoquecongradasLabel.Text = 'Choque con gradas';
            app.v1v27836msLabel = uilabel(app.HallazgosTab);
            app.v1v27836msLabel.Text = 'v1 = v2 = 7.836 m/s';
            app.DireccinderechaambosLabel = uilabel(app.HallazgosTab);
            app.DireccinderechaambosLabel.Text = 'Dirección = derecha ambos';
            app.ChoqueelsticoLabel = uilabel(app.HallazgosTab);
            app.ChoqueelsticoLabel.Text = 'Choque elástico';
            app.v17836msLabel = uilabel(app.HallazgosTab);
            app.v17836msLabel.Text = 'v1 = -7.836 m/s';
            app.v2157msLabel = uilabel(app.HallazgosTab);
            app.v2157msLabel.Text = 'v2 = 15.7 m/s';
            app.Direccinauto1izquierdaLabel = uilabel(app.HallazgosTab);
            app.Direccinauto1izquierdaLabel.Text = 'Dirección auto 1 = izquierda';
            app.Direccinauto2DerechaLabel = uilabel(app.HallazgosTab);
            app.Direccinauto2DerechaLabel.Text = 'Dirección auto 2 = Derecha';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RetoF1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end